classdef EEG2SPM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEG2SPMToolboxUIFigure        matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TriggercodeEditField          matlab.ui.control.NumericEditField
        TriggercodeEditFieldLabel     matlab.ui.control.Label
        EventendcodeEditField         matlab.ui.control.NumericEditField
        EventendcodeEditFieldLabel    matlab.ui.control.Label
        EventonsetcodeEditField       matlab.ui.control.NumericEditField
        EventonsetcodeEditFieldLabel  matlab.ui.control.Label
        NrofsessionsEditField         matlab.ui.control.NumericEditField
        NrofsessionsEditFieldLabel    matlab.ui.control.Label
        EEG2SPMLabel                  matlab.ui.control.Label
        LOADEEGButton                 matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        fMRIrepetitiontimeEditField   matlab.ui.control.NumericEditField
        fMRIrepetitiontimeEditFieldLabel  matlab.ui.control.Label
        DynamicanalysisCheckBox       matlab.ui.control.CheckBox
        ExportCSVCheckBox             matlab.ui.control.CheckBox
        ExportNiftimasksCheckBox      matlab.ui.control.CheckBox
        SerialcorrAR1CheckBox         matlab.ui.control.CheckBox
        CorrectionDropDown            matlab.ui.control.DropDown
        CorrectionDropDownLabel       matlab.ui.control.Label
        PvalueEditField               matlab.ui.control.NumericEditField
        PvalueEditFieldLabel          matlab.ui.control.Label
        RUNButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        EF % Description
        nros
        xpath
        codestat
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LeftPanel.AutoResizeChildren = 'off';
            app.RightPanel.AutoResizeChildren = 'off';
        end

        % Button pushed function: LOADEEGButton
        function LOADEEGButtonPushed(app, event)
            
            % Loading EEG data, extracting information about triggers and events, preprocessing

            if ~isempty(which('ft_defaults'))

                ft_defaults
                app.EF.EEG=struct([]);

                try

                    for i=1:app.NrofsessionsEditField.Value

                        uiwait(msgbox('SELECT EEG data','Select data'));  %select eeg data
                        [name, folder] = uigetfile('*.*');
                        app.EF.EEG(i).path= fullfile(folder, name);
                        clear name
                        cd (folder)
                    end

                    for i=1:app.NrofsessionsEditField.Value

                        %define config informations for ft processing, process the data
                        app.EF.EEG(i).cfg           = [];
                        app.EF.EEG(i).cfg.dataset    = app.EF.EEG(i).path;
                        app.EF.EEG(i).cfg.continuous = 'yes';
                        app.EF.EEG(i).cfg.channel    = { 'FP1' 'FP2' 'F7' 'F8' 'T7' 'T8' 'C5' 'C6' 'P5' 'P6' };
                        app.EF.EEG(i).data = ft_preprocessing(app.EF.EEG(i).cfg);
                        %get events
                        event=ft_read_event(app.EF.EEG(i).path);
                        idx = ismember({event.type},'trigger');
                        event=event(idx);
                        event = struct2table(event); % convert the struct array to a table
                        app.EF.EEG(i).event.trigger = event(event.value==app.TriggercodeEditField.Value,2);
                        app.EF.EEG(i).event.start = event(event.value==app.EventonsetcodeEditField.Value,2);
                        app.EF.EEG(i).event.end = event(event.value==app.EventendcodeEditField.Value,2);

                        if ~isempty(app.EF.EEG(i).event.trigger) && ~isempty(app.EF.EEG(i).event.start) && ~isempty(app.EF.EEG(i).event.end)
                            app.EF.EEG(i).event.startdouble=(table2array(app.EF.EEG(i).event.start)-app.EF.EEG(i).event.trigger.sample(1))/1000;
                            app.EF.EEG(i).event.enddouble=(table2array(app.EF.EEG(i).event.end)-app.EF.EEG(i).event.trigger.sample(1))/1000;
                            app.EF.EEG(i).event.dur=app.EF.EEG(i).event.enddouble-app.EF.EEG(i).event.startdouble;
                        end

                        clear event idx
                    end

                    clc
                    figure(app.EEG2SPMToolboxUIFigure)

                    app.codestat=0;
                    if isempty(app.EF.EEG(i).event.trigger)
                        app.codestat = 1;
                        errordlg('No trigger codes found', 'BEWARE!');
                    end
                    if isempty(app.EF.EEG(i).event.start)
                        app.codestat = 1;
                        errordlg('No event onset codes found', 'BEWARE!');
                    end
                    if isempty(app.EF.EEG(i).event.end)
                        app.codestat = 1;
                        errordlg('No event end codes found', 'BEWARE!');
                    end
                    if app.codestat==0
                        uiwait(msgbox('EEG data loaded succesfully','WELL DONE!'));
                    end

                catch
                    figure(app.EEG2SPMToolboxUIFigure)
                end

            else
                errordlg('Make sure you have added the FieldTrip toolbox to your Matlab path','BEWARE!');
            end

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

            % Loading fMRI data (sr/swr recommended) and rp regressor file;
            % run fMRI analysis

            if isprop(app, 'EF') && ~isempty(app.EF) && app.codestat==0

                try
                    for i=1:app.NrofsessionsEditField.Value
                        uiwait(msgbox('SELECT fmri data and movement regressor','Select data'));
                        files = cellstr(spm_select(Inf,'image'));
                        for j=1:length(files)
                            files{j,1}=strcat(files{j,1},',1');
                        end
                        reg = cellstr(spm_select(1,'^rp_.*\.txt$'));
                        app.EF.fMRI(i).data=files;
                        app.EF.fMRI(i).rp=reg;
                        clear files reg
                    end


                    if app.DynamicanalysisCheckBox.Value == 1
                        uiwait(msgbox('Choose directory for SPM outputs','Choose dir'));
                        app.xpath=uigetdir('C:\','Choose directory');


                        for j=1:1:21

                            cd (app.xpath)
                            mkdir(num2str(j-11));

                            spm('defaults','fmri');
                            spm_jobman('initcfg');


                            matlabbatch{1}.spm.stats.fmri_spec.dir = {fullfile(app.xpath,num2str(j-11))};
                            matlabbatch{1}.spm.stats.fmri_spec.timing.units = 'secs';
                            matlabbatch{1}.spm.stats.fmri_spec.timing.RT = app.fMRIrepetitiontimeEditField.Value;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t = 16;
                            matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t0 = 8;

                            for i=1:app.NrofsessionsEditField.Value

                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).scans = app.EF.fMRI(i).data;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.name = 'EP';
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.onset = app.EF.EEG(i).event.startdouble+(j-11);
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.duration = 0.01;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.tmod = 0;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.pmod = struct('name', {}, 'param', {}, 'poly', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.orth = 1;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi = {''};
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).regress = struct('name', {}, 'val', {});
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi_reg = app.EF.fMRI(i).rp;
                                matlabbatch{1}.spm.stats.fmri_spec.sess(i).hpf = 128;
                            end

                            matlabbatch{1}.spm.stats.fmri_spec.fact = struct('name', {}, 'levels', {});
                            matlabbatch{1}.spm.stats.fmri_spec.bases.hrf.derivs = [0 0];
                            matlabbatch{1}.spm.stats.fmri_spec.volt = 1;
                            matlabbatch{1}.spm.stats.fmri_spec.global = 'None';
                            matlabbatch{1}.spm.stats.fmri_spec.mthresh = 0.8;
                            matlabbatch{1}.spm.stats.fmri_spec.mask = {''};

                            if app.SerialcorrAR1CheckBox.Value==1
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'AR(1)';
                            else
                                matlabbatch{1}.spm.stats.fmri_spec.cvi = 'none';
                            end

                            matlabbatch{2}.spm.stats.fmri_est.spmmat =  cellstr(fullfile(app.xpath,num2str(j-11), 'SPM.mat'));
                            matlabbatch{2}.spm.stats.fmri_est.write_residuals = 0;
                            matlabbatch{2}.spm.stats.fmri_est.method.Classical = 1;

                            %%%%

                            matlabbatch{3}.spm.stats.con.spmmat(1) = cfg_dep('Model estimation: SPM.mat File', substruct('.','val', '{}',{2}, '.','val', '{}',{1}, '.','val', '{}',{1}), substruct('.','spmmat'));

                            if app.NrofsessionsEditField.Value == 1
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.name = 'EP';
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.weights = 1;
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.sessrep = 'none';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EPminus';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = -1;
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.sessrep = 'none';

                            elseif app.NrofsessionsEditField.Value == 2

                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.name = 'EP';
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.weights = [1 0 0 0 0 0 0 1];
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.sessrep = 'none';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EPminus';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = [-1 0 0 0 0 0 0 -1];
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.sessrep = 'none';

                            elseif app.NrofsessionsEditField.Value == 3

                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.name = 'EP';
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.weights = [1 0 0 0 0 0 0 1 0 0 0 0 0 0 1];
                                matlabbatch{3}.spm.stats.con.consess{1}.tcon.sessrep = 'none';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EPminus';
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = [-1 0 0 0 0 0 0 -1 0 0 0 0 0 0 -1];
                                matlabbatch{3}.spm.stats.con.consess{2}.tcon.sessrep = 'none';
                            end


                            matlabbatch{3}.spm.stats.con.delete = 0;

                            matlabbatch{4}.spm.stats.results.spmmat(1) = {fullfile(app.xpath,num2str(j-11), 'SPM.mat')};
                            matlabbatch{4}.spm.stats.results.conspec(1).titlestr = '';
                            matlabbatch{4}.spm.stats.results.conspec(1).contrasts = Inf;

                            if strcmpi('FWE',app.DataextractionDropDown.Value)
                                matlabbatch{4}.spm.stats.results.conspec(1).threshdesc = 'FWE';
                            else
                                matlabbatch{4}.spm.stats.results.conspec(1).threshdesc = 'none';
                            end

                            matlabbatch{4}.spm.stats.results.conspec(1).thresh = app.PvalueEditField.Value;
                            matlabbatch{4}.spm.stats.results.conspec(1).extent = 0;
                            matlabbatch{4}.spm.stats.results.conspec(1).conjunction = 1;
                            matlabbatch{4}.spm.stats.results.conspec(1).mask.none = 1;
                            matlabbatch{4}.spm.stats.results.units = 1;
                            matlabbatch{4}.spm.stats.results.export{1}.csv = true;
                            matlabbatch{4}.spm.stats.results.export{2}.xls = true;
                            matlabbatch{4}.spm.stats.results.export{3}.ps = true;
                            matlabbatch{4}.spm.stats.results.export{4}.jpg = true;
                            matlabbatch{4}.spm.stats.results.export{5}.binary.basename = 'activationcluster';


                            %


                            spm_jobman('run',matlabbatch);
                            clear matlabbatch
                        end


                    else


                        uiwait(msgbox('Choose directory for SPM outputs','Choose dir'));
                        app.xpath=uigetdir('C:\','Choose directory');
                        cd (app.xpath)


                        mkdir spmreg

                        spm('defaults','fmri');
                        spm_jobman('initcfg');

                        matlabbatch{1}.spm.stats.fmri_spec.dir = cellstr(fullfile(app.xpath,'spmreg'));
                        matlabbatch{1}.spm.stats.fmri_spec.timing.units = 'secs';
                        matlabbatch{1}.spm.stats.fmri_spec.timing.RT = app.fMRIrepetitiontimeEditField.Value;
                        matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t = 16;
                        matlabbatch{1}.spm.stats.fmri_spec.timing.fmri_t0 = 8;

                        for i=1:app.NrofsessionsEditField.Value

                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).scans = app.EF.fMRI(i).data;
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.name = 'EP';
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.onset = app.EF.EEG(i).event.startdouble;
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.duration = app.EF.EEG(i).event.dur;
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.tmod = 0;
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.pmod = struct('name', {}, 'param', {}, 'poly', {});
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).cond.orth = 1;
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi = {''};
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).regress = struct('name', {}, 'val', {});
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).multi_reg = app.EF.fMRI(i).rp;
                            matlabbatch{1}.spm.stats.fmri_spec.sess(i).hpf = 128;
                        end

                        matlabbatch{1}.spm.stats.fmri_spec.fact = struct('name', {}, 'levels', {});
                        matlabbatch{1}.spm.stats.fmri_spec.bases.hrf.derivs = [0 0];
                        matlabbatch{1}.spm.stats.fmri_spec.volt = 1;
                        matlabbatch{1}.spm.stats.fmri_spec.global = 'None';
                        matlabbatch{1}.spm.stats.fmri_spec.mthresh = 0.8;
                        matlabbatch{1}.spm.stats.fmri_spec.mask = {''};

                        if app.SerialcorrAR1CheckBox.Value==1
                            matlabbatch{1}.spm.stats.fmri_spec.cvi = 'AR(1)';
                        else
                            matlabbatch{1}.spm.stats.fmri_spec.cvi = 'none';
                        end

                        matlabbatch{2}.spm.stats.fmri_est.spmmat =  cellstr(strcat(app.xpath,'/spmreg/', 'SPM.mat'));
                        matlabbatch{2}.spm.stats.fmri_est.write_residuals = 0;
                        matlabbatch{2}.spm.stats.fmri_est.method.Classical = 1;

                        %%%%

                        matlabbatch{3}.spm.stats.con.spmmat(1) = cfg_dep('Model estimation: SPM.mat File', substruct('.','val', '{}',{2}, '.','val', '{}',{1}, '.','val', '{}',{1}), substruct('.','spmmat'));

                        matlabbatch{3}.spm.stats.con.consess{1}.tcon.name = 'EP1';
                        matlabbatch{3}.spm.stats.con.consess{1}.tcon.weights = 1;
                        matlabbatch{3}.spm.stats.con.consess{1}.tcon.sessrep = 'none';

                        if app.NrofsessionsEditField.Value == 2
                            matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EP2';
                            matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = [0 0 0 0 0 0 0 1];
                            matlabbatch{3}.spm.stats.con.consess{2}.tcon.sessrep = 'none';
                            matlabbatch{3}.spm.stats.con.consess{3}.tcon.name = 'EP';
                            matlabbatch{3}.spm.stats.con.consess{3}.tcon.weights = [1 0 0 0 0 0 0 1];
                            matlabbatch{3}.spm.stats.con.consess{3}.tcon.sessrep = 'none';


                        elseif app.NrofsessionsEditField.Value == 3
                            matlabbatch{3}.spm.stats.con.consess{2}.tcon.name = 'EP2';
                            matlabbatch{3}.spm.stats.con.consess{2}.tcon.weights = [0 0 0 0 0 0 0 1];
                            matlabbatch{3}.spm.stats.con.consess{3}.tcon.name = 'EP3';
                            matlabbatch{3}.spm.stats.con.consess{3}.tcon.weights = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 1];
                            matlabbatch{3}.spm.stats.con.consess{3}.tcon.sessrep = 'none';
                            matlabbatch{3}.spm.stats.con.consess{4}.tcon.name = 'EP';
                            matlabbatch{3}.spm.stats.con.consess{4}.tcon.weights = [1 0 0 0 0 0 0 1 0 0 0 0 0 0 1];
                            matlabbatch{3}.spm.stats.con.consess{4}.tcon.sessrep = 'none';
                        end


                        matlabbatch{3}.spm.stats.con.delete = 0;

                        matlabbatch{4}.spm.stats.results.spmmat(1) = cfg_dep('Contrast Manager: SPM.mat File', substruct('.','val', '{}',{3}, '.','val', '{}',{1}, '.','val', '{}',{1}), substruct('.','spmmat'));
                        matlabbatch{4}.spm.stats.results.conspec(1).titlestr = '';
                        matlabbatch{4}.spm.stats.results.conspec(1).contrasts = Inf;

                        if strcmpi('FWE',app.CorrectionDropDown.Value)
                            matlabbatch{4}.spm.stats.results.conspec(1).threshdesc = 'FWE';
                        else
                            matlabbatch{4}.spm.stats.results.conspec(1).threshdesc = 'none';
                        end

                        matlabbatch{4}.spm.stats.results.conspec(1).thresh = app.PvalueEditField.Value;
                        matlabbatch{4}.spm.stats.results.conspec(1).thresh = 0.05;
                        matlabbatch{4}.spm.stats.results.conspec(1).extent = 0;
                        matlabbatch{4}.spm.stats.results.conspec(1).conjunction = 1;
                        matlabbatch{4}.spm.stats.results.conspec(1).mask.none = 1;
                        matlabbatch{4}.spm.stats.results.units = 1;

                        if app.ExportCSVCheckBox.Value == 1
                            matlabbatch{4}.spm.stats.results.export{1}.csv = true;
                            matlabbatch{4}.spm.stats.results.export{2}.xls = true;
                            matlabbatch{4}.spm.stats.results.export{3}.ps = true;
                            matlabbatch{4}.spm.stats.results.export{4}.jpg = true;
                            if app.ExportNiftimasksCheckBox.Value == 1
                                matlabbatch{4}.spm.stats.results.export{5}.binary.basename = 'cluster';
                            end
                        end

                        if app.ExportCSVCheckBox.Value == 0 && app.ExportNiftimasksCheckBox.Value == 1
                            matlabbatch{4}.spm.stats.results.export{1}.binary.basename = 'cluster';
                        end

                        %


                        spm_jobman('run',matlabbatch);
                    end

                catch
                    figure(app.EEG2SPMToolboxUIFigure)
                end

            else
                errordlg('Load proper EEG data first.','BEWARE!');
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEG2SPMToolboxUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {424, 424};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {373, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEG2SPMToolboxUIFigure = uifigure('Visible', 'off');
            app.EEG2SPMToolboxUIFigure.Name = 'EEG2SPM Toolbox';
            app.EEG2SPMToolboxUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEG2SPMToolboxUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LOADEEGButton = uibutton(app.LeftPanel, 'push');
            app.LOADEEGButton.ButtonPushedFcn = createCallbackFcn(app, @LOADEEGButtonPushed, true);
            app.LOADEEGButton.Text = 'LOAD EEG';
            app.EEG2SPMLabel = uilabel(app.LeftPanel);
            app.EEG2SPMLabel.Text = 'EEG2SPM';
            app.NrofsessionsEditFieldLabel = uilabel(app.LeftPanel);
            app.NrofsessionsEditFieldLabel.Text = 'Nr of sessions';
            app.NrofsessionsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NrofsessionsEditField.Limits = [1 Inf];
            app.NrofsessionsEditField.Value = 1;
            app.EventonsetcodeEditFieldLabel = uilabel(app.LeftPanel);
            app.EventonsetcodeEditFieldLabel.Text = 'Event onset code';
            app.EventonsetcodeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EventonsetcodeEditField.Value = 201;
            app.EventendcodeEditFieldLabel = uilabel(app.LeftPanel);
            app.EventendcodeEditFieldLabel.Text = 'Event end code';
            app.EventendcodeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EventendcodeEditField.Value = 202;
            app.TriggercodeEditFieldLabel = uilabel(app.LeftPanel);
            app.TriggercodeEditFieldLabel.Text = 'Trigger code';
            app.TriggercodeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.RUNButton = uibutton(app.RightPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.PvalueEditFieldLabel = uilabel(app.RightPanel);
            app.PvalueEditFieldLabel.Text = 'P value';
            app.PvalueEditField = uieditfield(app.RightPanel, 'numeric');
            app.PvalueEditField.Value = 0.05;
            app.CorrectionDropDownLabel = uilabel(app.RightPanel);
            app.CorrectionDropDownLabel.Text = 'Correction';
            app.CorrectionDropDown = uidropdown(app.RightPanel);
            app.CorrectionDropDown.Items = {'FWE', 'uncorrected'};
            app.CorrectionDropDown.Value = 'FWE';
            app.SerialcorrAR1CheckBox = uicheckbox(app.RightPanel);
            app.SerialcorrAR1CheckBox.Text = 'Serial corr: AR(1)';
            app.ExportNiftimasksCheckBox = uicheckbox(app.RightPanel);
            app.ExportNiftimasksCheckBox.Text = 'Export: Nifti masks';
            app.ExportNiftimasksCheckBox.Value = true;
            app.ExportCSVCheckBox = uicheckbox(app.RightPanel);
            app.ExportCSVCheckBox.Text = 'Export: CSV';
            app.ExportCSVCheckBox.Value = true;
            app.DynamicanalysisCheckBox = uicheckbox(app.RightPanel);
            app.DynamicanalysisCheckBox.Text = 'Dynamic analysis';
            app.fMRIrepetitiontimeEditFieldLabel = uilabel(app.RightPanel);
            app.fMRIrepetitiontimeEditFieldLabel.Text = 'fMRI repetition time';
            app.fMRIrepetitiontimeEditField = uieditfield(app.RightPanel, 'numeric');
            app.fMRIrepetitiontimeEditField.Value = 2.5;
            % Show the figure after all components are created
            app.EEG2SPMToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG2SPM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEG2SPMToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEG2SPMToolboxUIFigure)
        end
    end
end